classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ChoosefolderButton        matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        ChooseimageDropDownLabel  matlab.ui.control.Label
        ChooseimageDropDown       matlab.ui.control.DropDown
        ShowimageButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Y % target vector of omo/eterogeneous lesions
        files % vector of images name found
        stacknii % struct of all read nifti
    end
    

    methods (Access = private)

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            path = uigetdir();
            files_o = dir(fullfile([path '\homogeneous\*.nii']));
            files_e = dir(fullfile([path '\heterogeneous\*.nii']));
            files_all = [files_o; files_e];
            % Create target vector
            n_o = size(files_o, 1); % Number of homogeneous tumor patients (obs)
            n_e = size(files_e, 1); % Number of heterogeneous tumor patients (obs)
            isHeterogeneous = [zeros(n_o, 1); ones(n_e, 1)];
            
            for n = 1:size(files_all, 1)
                pathtemp = [files_all(n).folder '\' files_all(n).name];
                nii = load_nii(pathtemp);
                
                stacknifti(n, :) = nii;
                %stackimg(n, :, :, :) = double(nii.img);
            end
            
            % Set global app values
            app.files = files_all;
            app.Y = isHeterogeneous;
            app.stacknii = stacknifti;
            
            % Populate dropDown with images names
            ListOfFiles = {files_all(:).name};
            set(app.ChooseimageDropDown, 'Items', ListOfFiles)
            set(app.ChooseimageDropDown, 'Value', ListOfFiles(1))
            set(app.ChooseimageDropDown, 'ItemsData', stacknifti)

        end

        % Button pushed function: ShowimageButton
        function showImg(app, event)
            nii = app.ChooseimageDropDown.Value;

            % "Fix" the visualization based on the output/color scale
            minimum = min(min(min(nii.img)));% disp(num2str(minimum));
            maximum = max(max(max(nii.img)));% disp(num2str(maximum));
            % Normalize the image between 0 and 1
            img = nii.img - minimum;
            img = img / maximum;
            img = img * 1;
            
            % Visualize image
            figure();
            montage(img);
            colormap(jet)
            caxis('auto');
            colorbar
            set(gcf, 'Name', ['18F-FDG PET-CT' nii.fileprefix]);
            %app.ChooseimageDropDown.ItemsData(i).fileprefix
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChoosefolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Tooltip = {'Folder of homog. and heterog. lesions'};
            app.ChoosefolderButton.Text = 'Choose folder';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ChooseimageDropDownLabel = uilabel(app.Panel);
            app.ChooseimageDropDownLabel.Text = 'Choose image';
            app.ChooseimageDropDown = uidropdown(app.Panel);
            app.ChooseimageDropDown.Items = {'None'};
            app.ChooseimageDropDown.Value = 'None';
            app.ShowimageButton = uibutton(app.Panel, 'push');
            app.ShowimageButton.ButtonPushedFcn = createCallbackFcn(app, @showImg, true);
            app.ShowimageButton.Text = 'Show image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end