classdef app1_funzionante_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelezionacartellaButton         matlab.ui.control.Button
        AnalisiimmaginiPETPanel         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        VisualizzazioneTab              matlab.ui.container.Tab
        SelezionaimmagineDropDownLabel  matlab.ui.control.Label
        SelezionaimmagineDropDown       matlab.ui.control.DropDown
        MostraimmagineButton            matlab.ui.control.Button
        ClassificaimmagineButton        matlab.ui.control.Button
        FeedbackTextAreaLabel           matlab.ui.control.Label
        FeedbackTextArea                matlab.ui.control.TextArea
        ModellierisultatiTab            matlab.ui.container.Tab
        MostrarisultatiButton           matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        AvanzateButton_2                matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Y % target vector of omo/eterogeneous lesions
        stacknii % struct of all read nifti
        files % vector of images name found
        features % struct of all features calculated from get_features
        models % name of all models tested
        results % performances of models
        lasso_beta 
    end
    
    methods (Access = public)
        
        function features = get_features(app)
            features = calc_features(app.stacknii);
        end
    end   

    methods (Access = private)

        % Button pushed function: SelezionacartellaButton
        function SelezionacartellaButtonPushed(app, event)
            path = uigetdir();
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on'; % otherwise the window goes on background
            files_o = dir(fullfile([path '\homogeneous\*.nii']));
            files_e = dir(fullfile([path '\heterogeneous\*.nii']));
            files_all = [files_o; files_e];
            % Create target vector
            n_o = size(files_o, 1); % Number of homogeneous tumor patients (obs)
            n_e = size(files_e, 1); % Number of heterogeneous tumor patients (obs)
            isHeterogeneous = [zeros(n_o, 1); ones(n_e, 1)];
            
            for n = 1:size(files_all, 1)
                pathtemp = [files_all(n).folder '\' files_all(n).name];
                nii = load_nii(pathtemp);
                
                stacknifti(n, :) = nii;
                %stackimg(n, :, :, :) = double(nii.img);
            end
            
            % Set global app values
            app.Y = isHeterogeneous;
            app.stacknii = stacknifti;
            
            % Populate dropDown with images names
            ListOfFiles = {files_all(:).name};
            set(app.SelezionaimmagineDropDown, 'Items', ListOfFiles)
            set(app.SelezionaimmagineDropDown, 'Value', ListOfFiles(1))
            set(app.SelezionaimmagineDropDown, 'ItemsData', stacknifti)

        end

        % Button pushed function: MostraimmagineButton
        function showImg2(app, event)
            nii = app.SelezionaimmagineDropDown.Value;

            % "Fix" the visualization based on the output/color scale
            minimum = min(min(min(nii.img)));% disp(num2str(minimum));
            maximum = max(max(max(nii.img)));% disp(num2str(maximum));
            % Normalize the image between 0 and 1
            img = nii.img - minimum;
            img = img / maximum;
            img = img * 1;
           
            % Visualize image
            figure();
            montage(img);
            colormap(jet)
            caxis('auto');
            c = colorbar;
            %c.Color = 'white';
            set(gcf, 'Name', ['18F-FDG PET-CT ' nii.fileprefix]);
            %app.SelezionaimmagineDropDown.ItemsData(i).fileprefix
        end

        % Value changed function: MostrarisultatiButton
        function showResult(app, event)
            rng('default');
            tallrng('default');
            value = app.MostrarisultatiButton.Value;
            %% Create struct of features
            app.features = app.get_features();
            %%% TODO %%%
            [lasso_fit, lasso_coef, lasso_cm] = train_classifier(app.Y, app.features, "lasso");
            [en_02_fit, en_02_coef, en_02_cm] = train_classifier(app.Y, app.features, "elasticnet", 0.2);
            [en_05_fit, en_05_coef, en_05_cm] = train_classifier(app.Y, app.features, "elasticnet", 0.5);
            [en_08_fit, en_08_coef, en_08_cm] = train_classifier(app.Y, app.features, "elasticnet", 0.8);
            %pca_features = get_pca_features(app.features); % TO CHANGE 
            [svm_fit, svm_coef, svm_cm]       = train_classifier(app.Y, app.features, "svm");
            app.lasso_beta = lasso_coef;
            cm_mtrxs(1).cm = lasso_cm;
            cm_mtrxs(2).cm = en_02_cm;
            cm_mtrxs(3).cm = en_05_cm;
            cm_mtrxs(4).cm = en_08_cm;
            cm_mtrxs(5).cm = svm_cm;
            
            app.models = ["Lasso", "Elastic Net (alpha 0.2)", "Elastic Net (alpha 0.5)", ...
                "Elastic Net (alpha 0.8)", "PCA + SVM"];
            for i = 1:size(cm_mtrxs, 2)
                cm_mtrxs(i).acc  = (cm_mtrxs(i).cm(1,1) + cm_mtrxs(i).cm(2,2)) / sum(sum(cm_mtrxs(i).cm));
                cm_mtrxs(i).sens = cm_mtrxs(i).cm(2,2) / sum(cm_mtrxs(i).cm(2,:));
                cm_mtrxs(i).spec = cm_mtrxs(i).cm(1,1) / sum(cm_mtrxs(i).cm(1,:));
            end
            app.results = cm_mtrxs;
            
            [~, idx] = max([cm_mtrxs.acc]);            
            app.UITable.Visible = true;
            app.UITable.ColumnName = app.models(idx);
            app.UITable.Data = {round(1 - cm_mtrxs(idx).acc, 3); round(cm_mtrxs(idx).acc, 3);...
                round(cm_mtrxs(idx).sens,3); round(cm_mtrxs(idx).spec,3)};
        end

        % Callback function
        function AvanzateButtonPushed(app, event)
            value = app.AvanzateButtonPushed.Value;
            if value==0
                app.UITable2.Visible = true;
                app.UITable2.ColumnName = {'Lasso', 'EN (alpha 0.2)', 'EN (alpha 0.5)', 'EN (alpha 0.8)', 'SVM'};
                d = {app.results(1).acc, app.results(2).acc, app.results(3).acc,app.results(4).acc,app.results(5).acc;...
                    app.results(1).sens, app.results(2).sens, app.results(3).sens, app.results(4).sens, app.results(5).sens;...
                    app.results(1).spec, app.results(2).spec, app.results(3).spec, app.results(4).spec, app.results(5).spec};
                app.UITable2.Data = d;
            else
                app.UITable2.Visible =false;
            end                
        end

        % Value changed function: AvanzateButton_2
        function provabottone2(app, event)
            value = app.AvanzateButton_2.Value;
            if value==1
                app.UITable2.Visible = true;
                app.UITable2.ColumnName = {'Lasso', 'EN (alpha 0.2)', 'EN (alpha 0.5)', 'EN (alpha 0.8)', 'SVM'};
                d = {app.results(1).acc, app.results(2).acc, app.results(3).acc,app.results(4).acc,app.results(5).acc;...
                    app.results(1).sens, app.results(2).sens, app.results(3).sens, app.results(4).sens, app.results(5).sens;...
                    app.results(1).spec, app.results(2).spec, app.results(3).spec, app.results(4).spec, app.results(5).spec};
                app.UITable2.Data = d;
            else
                app.UITable2.Visible =false;
            end
        end

        % Button pushed function: ClassificaimmagineButton
        function Classificazione(app, event)
            nifti = app.SelezionaimmagineDropDown.Value;
            yhat = classify(nifti, app.lasso_beta);
            if yhat == 0
                risposta = "omogenea";
            else
                risposta = "eterogenea";
            end
            app.FeedbackTextArea.Value = risposta;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelezionacartellaButton = uibutton(app.UIFigure, 'push');
            app.SelezionacartellaButton.ButtonPushedFcn = createCallbackFcn(app, @SelezionacartellaButtonPushed, true);
            app.SelezionacartellaButton.Tooltip = {'Folder of homog. and heterog. lesions'};
            app.SelezionacartellaButton.Text = 'Seleziona cartella';
            app.AnalisiimmaginiPETPanel = uipanel(app.UIFigure);
            app.AnalisiimmaginiPETPanel.Title = 'Analisi immagini PET';
            app.TabGroup = uitabgroup(app.AnalisiimmaginiPETPanel);
            app.VisualizzazioneTab = uitab(app.TabGroup);
            app.VisualizzazioneTab.Title = 'Visualizzazione';
            app.SelezionaimmagineDropDownLabel = uilabel(app.VisualizzazioneTab);
            app.SelezionaimmagineDropDownLabel.Text = 'Seleziona immagine';
            app.SelezionaimmagineDropDown = uidropdown(app.VisualizzazioneTab);
            app.SelezionaimmagineDropDown.Items = {'None'};
            app.SelezionaimmagineDropDown.Value = 'None';
            app.MostraimmagineButton = uibutton(app.VisualizzazioneTab, 'push');
            app.MostraimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @showImg2, true);
            app.MostraimmagineButton.Text = 'Mostra immagine';
            app.ClassificaimmagineButton = uibutton(app.VisualizzazioneTab, 'push');
            app.ClassificaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @Classificazione, true);
            app.ClassificaimmagineButton.Text = 'Classifica immagine';
            app.FeedbackTextAreaLabel = uilabel(app.VisualizzazioneTab);
            app.FeedbackTextAreaLabel.Text = 'Feedback';
            app.FeedbackTextArea = uitextarea(app.VisualizzazioneTab);
            app.ModellierisultatiTab = uitab(app.TabGroup);
            app.ModellierisultatiTab.Title = 'Modelli e risultati';
            app.MostrarisultatiButton = uibutton(app.ModellierisultatiTab, 'state');
            app.MostrarisultatiButton.ValueChangedFcn = createCallbackFcn(app, @showResult, true);
            app.MostrarisultatiButton.Text = 'Mostra risultati';
            app.UITable = uitable(app.ModellierisultatiTab);
            app.UITable.ColumnName = {'Performance'};
            app.UITable.RowName = {'Error'; 'Accuracy'; 'Sens. (omog.)'; 'Spec. (eter.)'};
            app.UITable.ColumnEditable = false;
            app.UITable.Visible = 'off';
            app.UITable2 = uitable(app.ModellierisultatiTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {'Acc.'; 'Sens.'; 'Spec.'};
            app.UITable2.Visible = 'off';
            app.AvanzateButton_2 = uibutton(app.ModellierisultatiTab, 'state');
            app.AvanzateButton_2.ValueChangedFcn = createCallbackFcn(app, @provabottone2, true);
            app.AvanzateButton_2.Text = 'Avanzate...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1_funzionante_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end