classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        LoadimagesTab               matlab.ui.container.Tab
        ChoosetrainingfolderButton  matlab.ui.control.Button
        Tab2                        matlab.ui.container.Tab
    end

    methods (Access = private)

        % Button pushed function: ChoosetrainingfolderButton
        function load_images(app, event)
            path = uigetdir; % path containing homo and hetero folders
            files_o = dir(fullfile([path 'homogeneous/*.nii']));
            files_e = dir(fullfile([path 'heterogeneous/*.nii']));
            files = [files_o; files_e];
            
            for i = 1:size(files, 1)
                temp_path = [files(i).folder '\' files(i).name];
                nii_image = load_nii(temp_path);
                img = double(nii_image.img);
                header = nii_image.hdr;
                % Normalize the image between 0 and 1
                %minimum = min(min(min(img)));% disp(num2str(minimum));
                %maximum = max(max(max(img)));% disp(num2str(maximum));
                %img = img - minimum;
                %img = img / maximum;
                stackimg(i,:,:,:) = img;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.LoadimagesTab = uitab(app.TabGroup);
            app.LoadimagesTab.Title = 'Load images';
            app.ChoosetrainingfolderButton = uibutton(app.LoadimagesTab, 'push');
            app.ChoosetrainingfolderButton.ButtonPushedFcn = createCallbackFcn(app, @load_images, true);
            app.ChoosetrainingfolderButton.Text = 'Choose training folder';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end